classdef PMulticapa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        ClasificarButton              matlab.ui.control.Button
        TasadeaprendizajeSliderLabel  matlab.ui.control.Label
        TasadeaprendizajeSlider       matlab.ui.control.Slider
        BorrarButton                  matlab.ui.control.Button
        ClaseButtonGroup              matlab.ui.container.ButtonGroup
        NaranjaButton                 matlab.ui.control.RadioButton
        AzulButton                    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        X % Patrones
        T %Clases
    end
    
    methods (Access = private)    
        function results = Main(app)
            Wij = BackPropagation(app,app.X,app.T,4);
            PlotLines(app,Wij);
        end
        function Wij = BackPropagation(app, X, T, H)
            %% Inicializacion
            %X = Patrones
            %H = numero de neronas en la capa oculta
            X = X';
            epochs = 2000000; %Numero de epocas
            errmin = 1e-9; %Error minimo deseado
            n = app.TasadeaprendizajeSlider.Value; %Tasa de aprendizaje
            [D,N] = size(X);  %D = Dimensiones de los patrones, N = numero de patrones
            X = [ones(1,N);X]; %Agrega una fila de 1 a la matriz de patrones (Umbral)
            Wij = rand(H, D+1); %Pesos aleatorios para la capa oculta
            Wjk = rand(1,H); %Pesos aleatorios para la capa de salida
            for i=1:epochs
                %%Propagacion.
                Xj = Wij*X; % Entradas por pesos de la capa oculta
                yj = Sigmoidal(app,Xj); % Se evalua la funcion sigmoidal a cada salida de la capa oculta
                Xk = Wjk*yj; %Entradas por pesos de la capa de salida
                zk = Sigmoidal(app,Xk); %Evaluacion de la funcion Sigmoidal para la capa de salida
                E = T-zk; %Calculo del error
                J = mean(E.^2); %Error cuadratico medio
                %%Retropropagacion
                dk = zk.*(1-zk).*E; %Δk
                Wjk = Wjk + n* dk*yj'; %Se actualizan los pesos de la capa de salida
                dj = (Wjk'*dk).*yj.*(1-yj); %Δj
                Wij = Wij + n*dj*X'; %Se actualizan los pesos de la capa oculta
                if J < errmin
                    return
                end
            end
            %zk
            
        end
        
        function y = Sigmoidal(~,x)
            y = 1./(1+exp(-x));
        end
        function r = PlotLines(app,Wij)
            xx = 0:0.01:1;
            tam = length(Wij)-1;
            for i=1:tam
                plot(app.UIAxes, xx, -(Wij(i,3)/Wij(i,2))*xx-(Wij(i,1)/Wij(i,2)));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            axis(app.UIAxes,[0 1 0 1]);
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
            hold(app.UIAxes, 'on' )
            if app.NaranjaButton.Value == true
                plot(app.UIAxes,loc(1),loc(2),'.',"Color", '#ffa500',"MarkerSize",15);
                app.X = [app.X; loc(1),loc(2)];
                app.T = [app.T, 0];
            elseif app.AzulButton.Value == true
                plot(app.UIAxes,loc(1),loc(2),'.',"Color", '#1B92EF',"MarkerSize",15);
                app.X = [app.X; loc(1),loc(2)];
                app.T = [app.T, 1];
            end
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            Main(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Red neuronal multicapa')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.TasadeaprendizajeSliderLabel = uilabel(app.UIFigure);
            app.TasadeaprendizajeSliderLabel.Text = 'Tasa de aprendizaje';
            app.TasadeaprendizajeSlider = uislider(app.UIFigure);
            app.TasadeaprendizajeSlider.Limits = [0 0.1];
            app.TasadeaprendizajeSlider.Value = 0.03;
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.Text = 'Borrar';
            app.ClaseButtonGroup = uibuttongroup(app.UIFigure);
            app.ClaseButtonGroup.Title = 'Clase';
            app.NaranjaButton = uiradiobutton(app.ClaseButtonGroup);
            app.NaranjaButton.Text = 'Naranja';
            app.NaranjaButton.Value = true;
            app.AzulButton = uiradiobutton(app.ClaseButtonGroup);
            app.AzulButton.Text = 'Azul';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PMulticapa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end